classdef Monte_Carlo_Pi_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Monte_Carlo_PiUIFigure      matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        NumberofpointsSpinner       matlab.ui.control.Spinner
        ComputePIButton             matlab.ui.control.Button
        NumberofpointsSpinnerLabel  matlab.ui.control.Label
        Result                      matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ComputePIButton, NumberofpointsSpinner
        function ComputePIButtonPushed(app, event)
            c = 0;
            cla(app.UIAxes);
            hold(app.UIAxes,"on");
            x = rand(app.NumberofpointsSpinner.Value,1);
            y = rand(app.NumberofpointsSpinner.Value,1);
            r = x.^2 + y.^2;
            c = c + sum(r<=1);
            PI = 4/(app.NumberofpointsSpinner.Value) * c;
            scatter(app.UIAxes,x(r>1),y(r>1),10,'red','filled');
            scatter(app.UIAxes,x(r<=1),y(r<=1),10,'green','filled');
            xlim(app.UIAxes,[0 1]);
            ylim(app.UIAxes,[0 1]);
            app.Result.Text = num2str(PI);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Monte_Carlo_PiUIFigure = uifigure('Visible', 'off');
            app.Monte_Carlo_PiUIFigure.Name = 'Montecarlo Pi';
            app.GridLayout = uigridlayout(app.Monte_Carlo_PiUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            zlabel(app.UIAxes, 'Z')
            app.Result = uilabel(app.GridLayout);
            app.Result.Text = '';
            app.NumberofpointsSpinnerLabel = uilabel(app.GridLayout);
            app.NumberofpointsSpinnerLabel.Text = 'Number of points';
            app.ComputePIButton = uibutton(app.GridLayout, 'push');
            app.ComputePIButton.ButtonPushedFcn = createCallbackFcn(app, @ComputePIButtonPushed, true);
            app.ComputePIButton.Text = 'Compute PI';
            app.NumberofpointsSpinner = uispinner(app.GridLayout);
            app.NumberofpointsSpinner.Limits = [1 Inf];
            app.NumberofpointsSpinner.ValueDisplayFormat = '%d';
            app.NumberofpointsSpinner.ValueChangedFcn = createCallbackFcn(app, @ComputePIButtonPushed, true);
            app.NumberofpointsSpinner.Value = 1;
            % Show the figure after all components are created
            app.Monte_Carlo_PiUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Monte_Carlo_Pi_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Monte_Carlo_PiUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Monte_Carlo_PiUIFigure)
        end
    end
end